classdef segmenter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SegmentationAppUIFigure         matlab.ui.Figure
        SegmenttableRANSACPanel         matlab.ui.container.Panel
        MaxplanedistancemSliderLabel    matlab.ui.control.Label
        MaxplanedistancemSlider         matlab.ui.control.Slider
        FindclustersPanel               matlab.ui.container.Panel
        MinclusterdistancemSliderLabel  matlab.ui.control.Label
        MinclusterdistancemSlider       matlab.ui.control.Slider
        Clustersfound0Label             matlab.ui.control.Label
        ThresholdclustersPanel          matlab.ui.container.Panel
        MinimumpointsEditFieldLabel     matlab.ui.control.Label
        MinimumpointsEditField          matlab.ui.control.NumericEditField
        numClustersThreshLabel          matlab.ui.control.Label
        ViewpointcloudsPanel            matlab.ui.container.Panel
        CroppedcloudButton              matlab.ui.control.Button
        OriginalcloudButton             matlab.ui.control.Button
        DisplayboundingprimitivesButton  matlab.ui.control.Button
        Image                           matlab.ui.control.Image
    end

    
    properties (Access = private)
        ptCloudCrop
        ptCloudOriginal % Description
        ptCloudNoTable % Description
        clusters % Description
        thresholdedClusters % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp("hello world")
            fv = stlread('~/speciale/data/geometries/scene8-ball-cube-binary.stl');
            app.ptCloudOriginal = pointCloud(fv.vertices);

            %% Find points within region of interest
            %UR5 radius is 0.85m - and coordinate system is anchored at UR-base.
            roi = [-0.85 0.85 -0.85 0.85 -0.2 1.2];
            roiIndices = findPointsInROI(app.ptCloudOriginal, roi);
            app.ptCloudCrop = select(app.ptCloudOriginal, roiIndices);
        end

        % Button pushed function: OriginalcloudButton
        function OriginalcloudButtonPushed(app, event)
            pcshow(app.ptCloudOriginal, 'MarkerSize', 40, 'Parent',app.UIAxes)
        end

        % Button pushed function: CroppedcloudButton
        function CroppedcloudButtonPushed(app, event)
            pcshow(app.ptCloudCrop, 'MarkerSize', 40)
        end

        % Value changed function: MaxplanedistancemSlider
        function MaxplanedistancemSliderValueChanged(app, event)
            maxDist = app.MaxplanedistancemSlider.Value;
            [model, inliers, outliers] = pcfitplane(app.ptCloudCrop, maxDist);
            plane1 = select(app.ptCloudCrop, inliers);
            app.ptCloudNoTable = select(app.ptCloudCrop, outliers);
            pcshow(app.ptCloudNoTable,'MarkerSize', 40)
        end

        % Value changed function: MinclusterdistancemSlider
        function MinclusterdistancemSliderValueChanged(app, event)
            if ~isempty(app.ptCloudNoTable)
                minDist = app.MinclusterdistancemSlider.Value;
                [labels, numClusters] = pcsegdist(app.ptCloudNoTable, minDist);
                pcshow(app.ptCloudNoTable.Location,labels, "MarkerSize", 40)
                colormap(hsv(numClusters))
                title('Clusters')
                app.Clustersfound0Label.Text = "#Clusters: " + string(numClusters);
                
                app.clusters = {}; %Reset clusters everytime slider is changed
                for i = 1:numClusters
                    cluster_points = app.ptCloudNoTable.Location(labels == i,:);
                    app.clusters{i} = cluster_points;
                end
            end
        end

        % Value changed function: MinimumpointsEditField
        function MinimumpointsEditFieldValueChanged(app, event)
            numPointsThreshold = app.MinimumpointsEditField.Value;
            if  ~isempty(app.clusters)
                indices = cellfun(@(x) length(x) > numPointsThreshold, app.clusters);
                app.thresholdedClusters = app.clusters(indices);
                app.numClustersThreshLabel.Text = "#Clusters: " + string(size(app.thresholdedClusters, 2));
                
                thresholdedCloud = pointCloud(vertcat(app.thresholdedClusters{:}));
                pcshow(thresholdedCloud, 'MarkerSize', 40);
            end
        end

        % Button pushed function: DisplayboundingprimitivesButton
        function DisplayboundingprimitivesButtonPushed(app, event)
            if ~isempty(app.thresholdedClusters)
                %Find bounding spheres:
                boundingSpheres = {}
                for i = 1:size(app.thresholdedClusters, 2)
                    [r, c] = ExactMinBoundSphere3D(app.thresholdedClusters{i});
                    bs.radius = r;
                    bs.center = c;
                    boundingSpheres{i} = bs;
                end
                
                %Display bounding spheres:
                pcshow(app.ptCloudCrop, 'MarkerSize', 40)
                hold on
                for i = 1:size(app.thresholdedClusters, 2)
                    r = boundingSpheres{i}.radius;
                    c = boundingSpheres{i}.center;
                    [x,y,z] = sphere(50);
                    surf(c(1) + x*r, c(2) + y*r, c(3) + z*r, 'FaceAlpha', 0.2);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SegmentationAppUIFigure = uifigure('Visible', 'off');
            app.SegmentationAppUIFigure.Name = 'Segmentation App';
            app.SegmenttableRANSACPanel = uipanel(app.SegmentationAppUIFigure);
            app.SegmenttableRANSACPanel.Title = 'Segment table (RANSAC)';
            app.MaxplanedistancemSliderLabel = uilabel(app.SegmenttableRANSACPanel);
            app.MaxplanedistancemSliderLabel.Text = 'Max plane distance [m]';
            app.MaxplanedistancemSlider = uislider(app.SegmenttableRANSACPanel);
            app.MaxplanedistancemSlider.Limits = [0 0.3];
            app.MaxplanedistancemSlider.ValueChangedFcn = createCallbackFcn(app, @MaxplanedistancemSliderValueChanged, true);
            app.MaxplanedistancemSlider.Value = 0.09;
            app.FindclustersPanel = uipanel(app.SegmentationAppUIFigure);
            app.FindclustersPanel.Title = 'Find clusters';
            app.MinclusterdistancemSliderLabel = uilabel(app.FindclustersPanel);
            app.MinclusterdistancemSliderLabel.Text = 'Min cluster distance [m]';
            app.MinclusterdistancemSlider = uislider(app.FindclustersPanel);
            app.MinclusterdistancemSlider.Limits = [0 0.5];
            app.MinclusterdistancemSlider.ValueChangedFcn = createCallbackFcn(app, @MinclusterdistancemSliderValueChanged, true);
            app.MinclusterdistancemSlider.Value = 0.11;
            app.Clustersfound0Label = uilabel(app.FindclustersPanel);
            app.Clustersfound0Label.Text = '#Clusters found: 0';
            app.ThresholdclustersPanel = uipanel(app.SegmentationAppUIFigure);
            app.ThresholdclustersPanel.Title = 'Threshold clusters';
            app.MinimumpointsEditFieldLabel = uilabel(app.ThresholdclustersPanel);
            app.MinimumpointsEditFieldLabel.Text = 'Minimum #points';
            app.MinimumpointsEditField = uieditfield(app.ThresholdclustersPanel, 'numeric');
            app.MinimumpointsEditField.RoundFractionalValues = 'on';
            app.MinimumpointsEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumpointsEditFieldValueChanged, true);
            app.MinimumpointsEditField.Value = 300;
            app.numClustersThreshLabel = uilabel(app.ThresholdclustersPanel);
            app.numClustersThreshLabel.Text = '#Clusters found: 0';
            app.ViewpointcloudsPanel = uipanel(app.SegmentationAppUIFigure);
            app.ViewpointcloudsPanel.Title = 'View point clouds';
            app.CroppedcloudButton = uibutton(app.ViewpointcloudsPanel, 'push');
            app.CroppedcloudButton.ButtonPushedFcn = createCallbackFcn(app, @CroppedcloudButtonPushed, true);
            app.CroppedcloudButton.Text = 'Cropped cloud';
            app.OriginalcloudButton = uibutton(app.ViewpointcloudsPanel, 'push');
            app.OriginalcloudButton.ButtonPushedFcn = createCallbackFcn(app, @OriginalcloudButtonPushed, true);
            app.OriginalcloudButton.Text = 'Original cloud';
            app.DisplayboundingprimitivesButton = uibutton(app.SegmentationAppUIFigure, 'push');
            app.DisplayboundingprimitivesButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayboundingprimitivesButtonPushed, true);
            app.DisplayboundingprimitivesButton.Text = 'Display bounding primitives';
            app.Image = uiimage(app.SegmentationAppUIFigure);
            app.Image.ImageSource = 'ptlcoud.png';
            % Show the figure after all components are created
            app.SegmentationAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = segmenter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SegmentationAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SegmentationAppUIFigure)
        end
    end
end